classdef deeplabcut_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FilesanalyzedListBox        matlab.ui.control.ListBox
        FilesanalyzedListBoxLabel   matlab.ui.control.Label
        removeselectedButton        matlab.ui.control.Button
        deeplabcutanalysisLabel     matlab.ui.control.Label
        AddfilesButton              matlab.ui.control.Button
        ExitButton                  matlab.ui.control.Button
        AnalyzeButton               matlab.ui.control.Button
        FilestoanalyzeListBox       matlab.ui.control.ListBox
        FilestoanalyzeListBoxLabel  matlab.ui.control.Label
        AnalysisTypeDropDown        matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        ymaze_files
        ymaze_paths
        ymaze_files_analyzed
        
        open_files
        open_paths
        open_files_analyzed
        
        object_files
        object_paths
        object_files_analyzed
    end
    
    methods (Access = private)
        
        function update_files_list(app)
            app.FilestoanalyzeListBox.Items = {};
            app.FilesanalyzedListBox.Items = {};
            
            switch app.AnalysisTypeDropDown.Value
                case 'Y-Maze'
                    remove_inds = [];
                    for i = length(app.ymaze_files):-1:1
                        if any(strcmp(app.ymaze_files(1:i-i),app.ymaze_files(i))) %remove duplicates
                            remove_inds(i) = [remove_inds 1];
                        elseif isempty(strfind(app.ymaze_files{i},'Ymaze_2')) %remove files that aren't Ymaze_2 DLC files
                            remove_inds(i) = [remove_inds 1];
                        end
                    end
                    app.ymaze_files(remove_inds) = [];
                    app.ymaze_paths(remove_inds) = [];
                    app.ymaze_files_analyzed(remove_inds) = [];
                    
                    for i = 1:length(app.ymaze_files)
                        if app.ymaze_files_analyzed(i)==0
                            app.FilestoanalyzeListBox.Items = [app.FilestoanalyzeListBox.Items, app.ymaze_files(i)];
                        else
                            app.FilesanalyzedListBox.Items = [app.FilestanalyzedListBox.Items, app.ymaze_files(i)];
                        end
                    end
                        
                case 'Open field'
                    remove_inds = [];
                    for i = length(app.open_files):-1:1
                        if any(strcmp(app.open_files(1:i-i),app.open_files(i))) %remove duplicates
                            remove_inds(i) = [remove_inds 1];
                        elseif isempty(strfind(app.open_files{i},'Redbox_2')) %remove files that aren't Redbox_2 DLC files
                            remove_inds(i) = [remove_inds 1];
                        end
                    end
                    app.open_files(remove_inds) = [];
                    app.open_paths(remove_inds) = [];
                    app.open_files_analyzed(remove_inds) = [];
                    
                    for i = 1:length(app.open_files)
                        if app.open_files_analyzed(i)==0
                            app.FilestoanalyzeListBox.Items = [app.FilestoanalyzeListBox.Items, app.open_files(i)];
                        else
                            app.FilesanalyzedListBox.Items = [app.FilestanalyzedListBox.Items, app.open_files(i)];
                        end
                    end
                    
                case 'Object recognition'
                    remove_inds = [];
                    for i = length(app.object_files):-1:1
                        if any(strcmp(app.object_files(1:i-i),app.object_files(i))) %remove duplicates
                            remove_inds(i) = [remove_inds 1];
                        elseif isempty(strfind(app.object_files{i},'Redbox_2')) %remove files that aren't Redbox_2 DLC files
                            remove_inds(i) = [remove_inds 1];
                        end
                    end
                    app.object_files(remove_inds) = [];
                    app.object_paths(remove_inds) = [];
                    app.object_files_analyzed(remove_inds) = [];
                    
                    for i = 1:length(app.object_files)
                        if app.object_files_analyzed(i)==0
                            app.FilestoanalyzeListBox.Items = [app.FilestoanalyzeListBox.Items, app.object_files(i)];
                        else
                            app.FilesanalyzedListBox.Items = [app.FilestanalyzedListBox.Items, app.object_files(i)];
                        end
                    end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: AddfilesButton
        function AddfilesButtonPushed(app, event)
            switch app.AnalysisTypeDropDown.Value
                case 'Y-Maze'
                    [new_files,new_paths] = uigetfile('*.csv',...
                        'Select One or More Ymaze CSV Files', ...
                        'MultiSelect', 'on');
                    app.ymaze_files = [app.ymaze_files new_files];
                    app.ymaze_paths = [app.ymaze_paths new_paths];
                    app.ymaze_files_analyzed = [app.ymaze_files_analyzed zeros(1,length(new_files))];
                
                case 'Open field'
                    [new_files,new_paths] = uigetfile('*.csv',...
                        'Select One or More Redbox CSV Files', ...
                        'MultiSelect', 'on');
                    app.open_files = [app.open_files new_files];
                    app.open_paths = [app.open_paths new_paths];
                    app.open_files_analyzed = [app.open_files_analyzed zeros(1,length(new_files))];
            
                case 'Object recognition'
                    [new_files,new_paths] = uigetfile('*.csv',...
                        'Select One or More Redbox CSV Files', ...
                        'MultiSelect', 'on');
                    app.object_files = [app.object_files new_files];
                    app.object_paths = [app.object_paths new_paths];
                    app.object_files_analyzed = [app.object_files_analyzed zeros(1,length(new_files))];
            
            end
            
            update_files_list(app)
   
        end

        % Value changed function: AnalysisTypeDropDown
        function AnalysisTypeDropDownValueChanged(app, event)
            update_files_list(app)
        end

        % Button pushed function: removeselectedButton
        function removeselectedButtonPushed(app, event)
            for i = 1:length(app.FilestoanalyzeListBox.Value)
                curval = app.FilestoanalyzeListBox.Value(i);
                switch app.AnalysisTypeDropDown.Value
                    case 'Y-Maze'
                        ind_to_remove = find(strcmp(app.ymaze_files,curval));
                        app.ymaze_files(ind_to_remove) = [];
                        app.ymaze_paths(ind_to_remove) = [];
                        
                    case 'Open field'
                        ind_to_remove = find(strcmp(app.open_files,curval));
                        app.open_files(ind_to_remove) = [];
                        app.open_paths(ind_to_remove) = [];
                        
                    case 'Object recognition'
                        ind_to_remove = find(strcmp(app.object_files,curval));
                        app.object_files(ind_to_remove) = [];
                        app.object_paths(ind_to_remove) = [];
                end
            end
            
            update_files_list(app)
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            switch app.AnalysisTypeDropDown.Value
                case 'Y-Maze'
                    for i = 1:length(app.ymaze_files)
                        try
                            analyze_ymaze(fullfile(app.ymaze_paths{i},app.ymaze_files{i}));
                            app.ymaze_files_analyzed(i) = 1;
                            update_files_list(app);
                            pause(0.01)
                        catch
                            app.ymaze_files_analyzed(i) = 0;
                            error(['could not analyze ' app.ymaze_files{i}]);
                        end
                    end
                case 'Open field'
                    for i = 1:length(app.open_files)
                        try
                            analyze_openfield(fullfile(app.open_paths{i},app.open_files{i}));
                            app.open_files_analyzed(i) = 1;
                            update_files_list(app);
                            pause(0.01)
                        catch
                            app.open_files_analyzed(i) = 0;
                            error(['could not analyze ' app.open_files{i}]);
                        end
                    end
                case 'Object recognition'
                    for i = 1:length(app.object_files)
                        try
                            analyze_objectrecognition(fullfile(app.object_paths{i},app.object_files{i}));
                            app.object_files_analyzed(i) = 1;
                            update_files_list(app);
                            pause(0.01)
                        catch
                            app.object_files_analyzed(i) = 0;
                            error(['could not analyze ' app.object_files{i}]);
                        end
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnalysisTypeDropDownLabel = uilabel(app.UIFigure);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.UIFigure);
            app.AnalysisTypeDropDown.Items = {'Y-Maze', 'Open field', 'Object recognition'};
            app.AnalysisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeDropDownValueChanged, true);
            app.AnalysisTypeDropDown.Value = 'Y-Maze';
            app.FilestoanalyzeListBoxLabel = uilabel(app.UIFigure);
            app.FilestoanalyzeListBoxLabel.Text = 'Files to analyze';
            app.FilestoanalyzeListBox = uilistbox(app.UIFigure);
            app.FilestoanalyzeListBox.Items = {'None added'};
            app.FilestoanalyzeListBox.Multiselect = 'on';
            app.FilestoanalyzeListBox.Value = {};
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.AddfilesButton = uibutton(app.UIFigure, 'push');
            app.AddfilesButton.ButtonPushedFcn = createCallbackFcn(app, @AddfilesButtonPushed, true);
            app.AddfilesButton.Text = 'Add files';
            app.deeplabcutanalysisLabel = uilabel(app.UIFigure);
            app.deeplabcutanalysisLabel.Text = 'deeplabcut analysis';
            app.removeselectedButton = uibutton(app.UIFigure, 'push');
            app.removeselectedButton.ButtonPushedFcn = createCallbackFcn(app, @removeselectedButtonPushed, true);
            app.removeselectedButton.Text = {'remove'; 'selected'};
            app.FilesanalyzedListBoxLabel = uilabel(app.UIFigure);
            app.FilesanalyzedListBoxLabel.Text = 'Files analyzed';
            app.FilesanalyzedListBox = uilistbox(app.UIFigure);
            app.FilesanalyzedListBox.Items = {};
            app.FilesanalyzedListBox.Enable = 'off';
            app.FilesanalyzedListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deeplabcut_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end